classdef Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UICollideSimUIFigure            matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        Cercle1Panel                    matlab.ui.container.Panel
        RayonEditFieldLabel             matlab.ui.control.Label
        RayonEditField                  matlab.ui.control.NumericEditField
        VitesseEditFieldLabel           matlab.ui.control.Label
        VitesseEditField                matlab.ui.control.NumericEditField
        MatriauDropDownLabel            matlab.ui.control.Label
        MatriauDropDown                 matlab.ui.control.DropDown
        CoordonnexducentreEditFieldLabel  matlab.ui.control.Label
        CoordonnexducentreEditField     matlab.ui.control.NumericEditField
        CoordonneyducentreEditFieldLabel  matlab.ui.control.Label
        CoordonneyducentreEditField     matlab.ui.control.NumericEditField
        DirectionduvecteurvitesseGaugeLabel  matlab.ui.control.Label
        DirectionduvecteurvitesseGauge  matlab.ui.control.SemicircularGauge
        DirectionduvecteurvitesseSlider  matlab.ui.control.Slider
        MasseEditFieldLabel             matlab.ui.control.Label
        MasseEditField                  matlab.ui.control.NumericEditField
        AnglevecteurvitesseEditFieldLabel  matlab.ui.control.Label
        AnglevecteurvitesseEditField    matlab.ui.control.NumericEditField
        gLabel                          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        cmsLabel                        matlab.ui.control.Label
        cmLabel                         matlab.ui.control.Label
        Cercle2Panel                    matlab.ui.container.Panel
        RayonEditField_2Label           matlab.ui.control.Label
        RayonEditField_2                matlab.ui.control.NumericEditField
        VitesseEditField_2Label         matlab.ui.control.Label
        VitesseEditField_2              matlab.ui.control.NumericEditField
        MatriauDropDown_2Label          matlab.ui.control.Label
        MatriauDropDown_2               matlab.ui.control.DropDown
        CoordonnexducentreEditField_2Label  matlab.ui.control.Label
        CoordonnexducentreEditField_2   matlab.ui.control.NumericEditField
        CoordonneyducentreEditField_2Label  matlab.ui.control.Label
        CoordonneyducentreEditField_2   matlab.ui.control.NumericEditField
        DirectionduvecteurvitesseGauge_2Label  matlab.ui.control.Label
        DirectionduvecteurvitesseGauge_2  matlab.ui.control.SemicircularGauge
        DirectionduvecteurvitesseSlider_2  matlab.ui.control.Slider
        MasseEditField_2Label           matlab.ui.control.Label
        MasseEditField_2                matlab.ui.control.NumericEditField
        AnglevecteurvitesseEditField_2Label  matlab.ui.control.Label
        AnglevecteurvitesseEditField_2  matlab.ui.control.NumericEditField
        gLabel_2                        matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        cmsLabel_2                      matlab.ui.control.Label
        cmLabel_2                       matlab.ui.control.Label
        BotePanel                       matlab.ui.container.Panel
        OrientationdelaboteGaugeLabel   matlab.ui.control.Label
        OrientationdelaboteGauge        matlab.ui.control.NinetyDegreeGauge
        DirectionOrientationDeLaBoite   matlab.ui.control.Slider
        HauteurdelaboteEditFieldLabel   matlab.ui.control.Label
        HauteurdelaboteEditField        matlab.ui.control.NumericEditField
        LargeurdelaboteEditFieldLabel   matlab.ui.control.Label
        LargeurdelaboteEditField        matlab.ui.control.NumericEditField
        cmLabel_3                       matlab.ui.control.Label
        cmLabel_4                       matlab.ui.control.Label
        SimulationOnAirLampLabel        matlab.ui.control.Label
        SimulationOnAirLamp             matlab.ui.control.Lamp
        SimulationButton                matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        TempsdesimulationEditFieldLabel  matlab.ui.control.Label
        TempsdesimulationEditField      matlab.ui.control.NumericEditField
        SimulationparnombredecollisionSwitch  matlab.ui.control.Switch
        NombredecollisionEditFieldLabel  matlab.ui.control.Label
        NombredecollisionEditField      matlab.ui.control.NumericEditField
        InterruptsimulationButton       matlab.ui.control.Button
        TempsactuelLabel                matlab.ui.control.Label
        TempsactuelEditField            matlab.ui.control.NumericEditField
        CompteurdecollisionsEditFieldLabel  matlab.ui.control.Label
        CompteurdecollisionsEditField   matlab.ui.control.NumericEditField
        ResultatsPanel                  matlab.ui.container.Panel
        Resultatcercle1Panel            matlab.ui.container.Panel
        VitessefinaleEditFieldLabel     matlab.ui.control.Label
        VitessefinaleEditField          matlab.ui.control.NumericEditField
        AnglevecteurvitessefinalEditFieldLabel  matlab.ui.control.Label
        AnglevecteurvitessefinalEditField  matlab.ui.control.NumericEditField
        DirectionduvecteurvitessefinalGaugeLabel  matlab.ui.control.Label
        DirectionduvecteurvitessefinalGauge  matlab.ui.control.SemicircularGauge
        ComposanteVXEditFieldLabel      matlab.ui.control.Label
        ComposanteVXEditField           matlab.ui.control.NumericEditField
        ComposanteVYEditFieldLabel      matlab.ui.control.Label
        ComposanteVYEditField           matlab.ui.control.NumericEditField
        cmsLabel_3                      matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Resultatcercle2Panel            matlab.ui.container.Panel
        VitessefinaleEditField_2Label   matlab.ui.control.Label
        VitessefinaleEditField_2        matlab.ui.control.NumericEditField
        AnglevecteurvitessefinalEditField_2Label  matlab.ui.control.Label
        AnglevecteurvitessefinalEditField_2  matlab.ui.control.NumericEditField
        DirectionduvecteurvitessefinalGauge_2Label  matlab.ui.control.Label
        DirectionduvecteurvitessefinalGauge_2  matlab.ui.control.SemicircularGauge
        ComposanteVXEditField_2Label    matlab.ui.control.Label
        ComposanteVXEditField_2         matlab.ui.control.NumericEditField
        ComposanteVYEditField_2Label    matlab.ui.control.Label
        ComposanteVYEditField_2         matlab.ui.control.NumericEditField
        cmsLabel_4                      matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Vitesseparmthode2EditFieldLabel  matlab.ui.control.Label
        Vitesseparmthode2EditField      matlab.ui.control.NumericEditField
        Vitesseparmthode2EditField_2Label  matlab.ui.control.Label
        Vitesseparmthode2EditField_2    matlab.ui.control.NumericEditField
        cmsLabel_5                      matlab.ui.control.Label
        cmsLabel_6                      matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        cmLabel_5                       matlab.ui.control.Label
        cmLabel_6                       matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        HoldSwitchLabel                 matlab.ui.control.Label
        HoldSwitch                      matlab.ui.control.ToggleSwitch
        AfficherAxesdecollisionsSwitchLabel  matlab.ui.control.Label
        AfficherAxesdecollisionsSwitch  matlab.ui.control.ToggleSwitch
    end

    
    properties (Access = public)
        %CERCLE 1
        xCircle1_Initial;
        yCircle1_Initial;
        radiusCircle1;
        materialsCircle1 = Materials.IRON;
        speedCircle1;
        speedThetaCircle1;
        firstSpeedThetaCircle1;
        firstXCircle1_Initial;
        firstYCircle1_Initial;
        %CERCLE 2
        xCircle2_Initial;
        yCircle2_Initial;
        radiusCircle2;
        materialsCircle2 = Materials.IRON;
        speedCircle2;
        speedThetaCircle2;
        firstSpeedThetaCircle2;
        firstXCircle2_Initial;
        firstYCircle2_Initial;
        %BOITE
        xBox_Initial;
        yBox_Initial;
        boxLenght;
        boxHeight;
        boxOrientation;
        %SIMULATION
        totalTime = 100;
        isSimulatingWithCollideNumber = '1';
        collideNumber = 1;
        precision = 0.03;
        circle1;
        circle2;
        box;
        vxc;
        vyc;
        vxc2;
        vyc2;
        xMatrixs1;
        yMatrixs1;
        xMatrixs2;
        yMatrixs2;
        xMatrixs3;
        yMatrixs3;
        xMatrixs4;
        yMatrixs4;
        circleMatrix;
        time;
        numCircleThatGoingToCollide;
        numOtherCircle;
        actualTime;
        actualTimeToShow;
        startTime;
        side;
        side1;
        side2;
        time1;
        time2;
        isThereACirclesCollide;
        simulationHasToBeStopped;
        actualCollideNumber;
        isHoldOn;
        isShowCollideAxisOn;
        verifiedSpeed1;
        verifiedSpeed2;
    end
    methods (Access = public)
        % Méthode pour récupérer le matériau choisi pour un cercle
        function materials = getMaterials(app, materialName)
            switch materialName
                case 'IRON'
                    materials = Materials.IRON;
                case 'SILVER'
                    materials = Materials.SILVER;
                case 'GOLD'
                    materials = Materials.GOLD;
                case 'COPPER'
                    materials = Materials.COPPER;
                case 'LEAD'
                    materials = Materials.LEAD;
                case 'WOOD'
                    materials = Materials.WOOD;
                case 'GLASS'
                    materials = Materials.GLASS;
                case 'PVC'
                    materials = Materials.PVC;
                case 'NYLON'
                    materials = Materials.NYLON;
                case 'CONCRETE'
                    materials = Materials.CONCRETE;
                case 'STEEL'
                    materials = Materials.STEEL;
                case 'ALUMINIUM'
                    materials = Materials.ALUMINIUM;
                case 'NICKEL'
                    materials = Materials.NICKEL;
                case 'TIN'
                    materials = Materials.TIN;
                case 'TITANIUM'
                    materials = Materials.TITANIUM;
                case 'BRONZE'
                    materials = Materials.BRONZE;
                case 'TUNGSTEN'
                    materials = Materials.TUNGSTEN;
                case 'LITHIUM'
                    materials = Materials.LITHIUM;
                case 'CLAY'
                    materials = Materials.CLAY;
                case 'GRANITE'
                    materials = Materials.GRANITE;
                case 'QUARTZ'
                    materials = Materials.QUARTZ;
                case 'MARBLE'
                    materials = Materials.MARBLE;
                case 'LIMESTONE'
                    materials = Materials.LIMESTONE;
            end
        end
    end
    
    methods (Access = private, Static)
        % Méthode pour exécuter la simulation
        function results = Simulation(app)
            clc;
            hold(app.UIAxes, 'off');
            hold off;
            disp('Début de la simulation');
            app.initSimulation(app);
            i = 1;
            j = 0;
            if app.isHoldOn
                disp('Activation du hold');
                hold(app.UIAxes, 'on');
            end
            % Simulation avec un nombre de collision
            if app.isSimulatingWithCollideNumber == '1'
                disp('Mode collision');
                while j < app.collideNumber
                    % LOG pour connaître le numéro de la collision
                    disp('COLLISION N°');
                    disp(j + 1);
                    % Indication de la vitesse initiale de chaque cercle
                    app.circle1.vVector.initialSpeed = app.circle1.vVector.speed;
                    app.circle2.vVector.initialSpeed = app.circle2.vVector.speed;
                    % Indication de la direction initiale du vecteur vitesse
                    app.circle1.vVector.initialTheta = app.circle1.vVector.theta;
                    app.circle2.vVector.initialTheta = app.circle2.vVector.theta;
                    % Repérage du côté vers lequel se dirige le cercle et calcul du temps
                    % qu'il faut pour aller vers celui-ci
                    [app.time1, app.side1] = getTargetSide(app.circle1, app.box );
                    [app.time2, app.side2] = getTargetSide(app.circle2, app.box );
                    if app.time1 > app.time2
                        app.side = app.side2;
                        app.time = app.time2;
                        app.numCircleThatGoingToCollide = 2;
                        app.numOtherCircle = 1;
                    else
                        app.side = app.side1;
                        app.time = app.time1;
                        app.numCircleThatGoingToCollide = 1;
                        app.numOtherCircle = 2;
                    end
                    % Pré simulation pour voir si les cercles vont entrer
                    % en collision
                    circleCollideTime = 0;
                    for t=0:0.00001:app.time
                        xcf = app.xCircle1_Initial + app.vxc*t;
                        ycf = app.yCircle1_Initial + app.vyc*t;
                        xcf2 = app.xCircle2_Initial + app.vxc2*t;
                        ycf2 = app.yCircle2_Initial + app.vyc2*t;
                        %Condition d'arrêt si distance < r1+r2
                        if circleIntersect(app.circle1,app.circle2,xcf,ycf,xcf2,ycf2)
                            circleCollideTime = t;
                            break
                        end
                    end
                    % Comparaison des temps des diverses collisions pour
                    % récupérer le temps de la première collision
                    if circleCollideTime < app.time && circleCollideTime > 0
                        app.isThereACirclesCollide = true;
                        app.time =circleCollideTime;
                    end
                    % Calcul des coordonnées finales des cercles
                    app.circle1.finalX = app.xCircle1_Initial + app.vxc*app.time;
                    app.circle1.finalY = app.yCircle1_Initial + app.vyc*app.time;
                    app.circle2.finalX = app.xCircle2_Initial + app.vxc2*app.time;
                    app.circle2.finalY = app.yCircle2_Initial + app.vyc2*app.time;
                    if app.isThereACirclesCollide
                        % Verification des résultats par une autre méthode
                        app.circle1
                        app.circle2
                        [speed1 , speed2] = compareResults2(app.circle1, app.circle2);
                        app.Vitesseparmthode2EditField.Value = speed1;
                        app.Vitesseparmthode2EditField_2.Value = speed2;
                    end
                    % Affichage de la simulation
                    app.showSimulation(app);
                    if app.simulationHasToBeStopped
                        break;
                    end
                    % Calcul du nouveau vecteur vitesse
                    app.setNewVVector(app);
                    % Iteration du nombre de collision
                    j = j + 1;
                    app.CompteurdecollisionsEditField.Value = j;
                end
                disp('Fin simulation');
            else
                disp('Mode temps');
                while app.actualTime <= app.totalTime
                    % LOG pour connaître le numéro de la collision
                    disp('COLLISION N°');
                    disp(j + 1);
                    % Indication de la vitesse initiale de chaque cercle
                    app.circle1.vVector.initialSpeed = app.circle1.vVector.speed;
                    app.circle2.vVector.initialSpeed = app.circle2.vVector.speed;
                    % Indication de la direction initiale du vecteur vitesse
                    app.circle1.vVector.initialTheta = app.circle1.vVector.theta;
                    app.circle2.vVector.initialTheta = app.circle2.vVector.theta;
                    app.actualCollideNumber = j + 1;
                    % Repérage du côté vers lequel se dirige le cercle et calcul du temps
                    % qu'il faut pour aller vers celui-ci
                    [app.time1, app.side1] = getTargetSide(app.circle1, app.box );
                    [app.time2, app.side2] = getTargetSide(app.circle2, app.box );
                    if app.time1 > app.time2
                        app.side = app.side2;
                        app.time = app.time2;
                        app.numCircleThatGoingToCollide = 2;
                        app.numOtherCircle = 1;
                    else
                        app.side = app.side1;
                        app.time = app.time1;
                        app.numCircleThatGoingToCollide = 1;
                        app.numOtherCircle = 2;
                    end
                    % Pré simulation pour voir si les cercles vont entrer
                    % en collision
                    circleCollideTime = 0;
                    for t=0:0.00001:app.time
                        xcf = app.xCircle1_Initial + app.vxc*t;
                        ycf = app.yCircle1_Initial + app.vyc*t;
                        xcf2 = app.xCircle2_Initial + app.vxc2*t;
                        ycf2 = app.yCircle2_Initial + app.vyc2*t;
                        %Condition d'arrêt si distance < r1+r2
                        if circleIntersect(app.circle1,app.circle2,xcf,ycf,xcf2,ycf2)
                            circleCollideTime = t;
                            break
                        end
                    end
                    % Comparaison des temps des diverses collisions pour
                    % récupérer le temps de la première collision
                    if circleCollideTime < app.time && circleCollideTime > 0
                        app.isThereACirclesCollide = true;
                        app.time =circleCollideTime;
                    end
                    % Calcul des coordonnées finales des cercles
                    app.circle1.finalX = app.xCircle1_Initial + app.vxc*app.time;
                    app.circle1.finalY = app.yCircle1_Initial + app.vyc*app.time;
                    app.circle2.finalX = app.xCircle2_Initial + app.vxc2*app.time;
                    app.circle2.finalY = app.yCircle2_Initial + app.vyc2*app.time;
                    if app.isThereACirclesCollide
                        % Verification des résultats par une autre méthode
                        [speed1 , speed2] = compareResults2(app.circle1, app.circle2);
                        app.Vitesseparmthode2EditField.Value = speed1;
                        app.Vitesseparmthode2EditField_2.Value = speed2;
                    end
                    % Affichage de la simulation
                    app.showSimulation(app);
                    % Calcul du nouveau vecteur vitesse
                    app.setNewVVector(app);
                    % Vérification du fait que le temps de simulation n'a pas dépassé le
                    % temps total
                    app.actualTime
                    if app.actualTime >= app.totalTime || app.simulationHasToBeStopped
                        break;
                    end
                    % Iteration du nombre de collision
                    j = j + 1;
                    app.CompteurdecollisionsEditField.Value = j;
                end
                disp('Fin simulation');
            end
            app.getFinalValues(app);
        end
        %Méthode permettant d'initialiser la simulation
        function results = initSimulation(app)
            disp('Début de l''initiation de la simulation');
            app.firstXCircle1_Initial = app.xCircle1_Initial;
            app.firstYCircle1_Initial = app.yCircle1_Initial;
            app.firstXCircle2_Initial = app.xCircle2_Initial;
            app.firstYCircle2_Initial = app.yCircle2_Initial;
            app.numCircleThatGoingToCollide = 1;
            app.numOtherCircle = 2;
            app.actualTime = 0;
            app.startTime = 0;
            %Creation de l'objet boîte
            app.box = Box(app.xBox_Initial, app.yBox_Initial,app.boxHeight, app.boxLenght, app.boxOrientation); % Création de la boîte
            %Recherche des coordonnées extrème de la boîte (utile
            %lorsqu'elle est inclinée)
            [xBoxExtremum , yBoxExtremum] = getExtremumCoord(app.box);
            %Définition des limites de la zone graphique pour l'affichage
            %de la boîte
            xlim(app.UIAxes,[-5 + app.xBox_Initial + xBoxExtremum(1,1),5 + app.xBox_Initial + xBoxExtremum(1,2)]);
            ylim(app.UIAxes, [-5 + app.yBox_Initial + yBoxExtremum(1,1),5 + app.yBox_Initial + yBoxExtremum(1,2)]);
            %Instanciation des cercles
            app.circle1 = cercle(app.xBox_Initial + app.xCircle1_Initial, app.yBox_Initial + app.yCircle1_Initial, app.radiusCircle1, app.speedCircle1, app.speedThetaCircle1, app.materialsCircle1); % Création du cercle
            app.circle2 = cercle(app.xBox_Initial + app.xCircle2_Initial, app.yBox_Initial + app.yCircle2_Initial, app.radiusCircle2, app.speedCircle2, app.speedThetaCircle2, app.materialsCircle2);
            % On vérifie que le cercle 1 est bien dans la boîte et qu'il ne
            % se trouve pas sur le cercle 2
            if ~verifyIfCircleIsInBox(app.circle1, app.box) || ~verifyIfCircleIsNotInOtherCircle(app.circle1, app.circle2)
                disp('PROBLEME DE PLACEMENT DU CERCLE');
                app.circle1 = placeRandomlyCircle(app.circle1,app.circle2, app.box);
            end
            %On vérifie que le cercle 2 est bien dans la boîte et qu'il ne
            % se trouve pas sur le cercle 1
            if ~verifyIfCircleIsInBox(app.circle2, app.box) || ~verifyIfCircleIsNotInOtherCircle(app.circle2, app.circle1)
                disp('PROBLEME DE PLACEMENT DU CERCLE');
                app.circle2 = placeRandomlyCircle(app.circle2,app.circle1, app.box);
            end
            % Initialisation des données utilisées pour le calcul et pour la
            % visualisation de la simulation
            [app.xCircle1_Initial, app.yCircle1_Initial] = getCoord(app.circle1);
            [app.vxc, app.vyc] = getVelocityComponents(app.circle1.vVector);
            [app.xCircle2_Initial, app.yCircle2_Initial] = getCoord(app.circle2);
            [app.vxc2,app.vyc2] = getVelocityComponents(app.circle2.vVector);
            [app.xMatrixs1, app.yMatrixs1] = getXAndYMatrixOfSide(app.box, 1);
            [app.xMatrixs2, app.yMatrixs2] = getXAndYMatrixOfSide(app.box, 2);
            [app.xMatrixs3, app.yMatrixs3] = getXAndYMatrixOfSide(app.box, 3);
            [app.xMatrixs4, app.yMatrixs4] = getXAndYMatrixOfSide(app.box, 4);
            app.firstSpeedThetaCircle1 = app.circle1.vVector.theta;
            app.firstSpeedThetaCircle2 = app.circle2.vVector.theta;
            hold off;
            hold(app.UIAxes, 'off');
            plot(app.UIAxes,app.xMatrixs1, app.yMatrixs1,'b', app.xMatrixs2, app.yMatrixs2,'b', app.xMatrixs3, app.yMatrixs3,'b', app.xMatrixs4, app.yMatrixs4, 'b');
            disp('Fin de l''initiation de la simulation');
        end
        %Méthode permettant d'animer les collisions
        function results = showSimulation(app)
            if ~app.isHoldOn
                hold off;
                hold(app.UIAxes, 'off');
                plot(app.UIAxes,app.xMatrixs1, app.yMatrixs1,'b', app.xMatrixs2, app.yMatrixs2,'b', app.xMatrixs3, app.yMatrixs3,'b', app.xMatrixs4, app.yMatrixs4, 'b');
            end
            disp('Début de la visualisation de la simulation');
            i =1;
            %Affichage du temps actuel de simulation
            app.TempsactuelEditField.Value = app.actualTime;
            theta = linspace(0, 2*pi);
            %Boucle pour animer la simulation
            for t=0:0.01:app.time
                if app.simulationHasToBeStopped
                    break;
                end
                % Vérification du fait que le temps de simulation n'a pas dépassé le
                % temps total si la simulation fonctionne en fonction
                % du temps
                if app.actualTime >= app.totalTime && app.isSimulatingWithCollideNumber == '0'
                    break;
                end
                %Calcul du temps de simulation actuel
                app.actualTimeToShow =  app.actualTimeToShow + t;
                app.actualTime = app.actualTime + t;
                app.TempsactuelEditField.Value = app.actualTimeToShow;
                %Affichage des cercles avec le calcul de leur point
                xcf = app.xCircle1_Initial + app.vxc*t;
                ycf = app.yCircle1_Initial + app.vyc*t;
                x = app.circle1.radius*cos(theta) + xcf;
                y = app.circle1.radius*sin(theta) + ycf;
                xcf2 = app.xCircle2_Initial  + app.vxc2*t;
                ycf2 = app.yCircle2_Initial + app.vyc2*t;
                x2 = app.circle2.radius*cos(theta) + xcf2;
                y2 = app.circle2.radius*sin(theta) + ycf2;
                %Affichage du graphe
                plot(app.UIAxes, x,y,'b', x2, y2, 'r', app.xMatrixs1, app.yMatrixs1,'b', app.xMatrixs2, app.yMatrixs2,'b', app.xMatrixs3, app.yMatrixs3,'b', app.xMatrixs4, app.yMatrixs4, 'b' );
                axis equal;
                %Méthode permettant l'animation
                anim(i)=getframe;
                i=i+1;
            end
            if app.isThereACirclesCollide && ~app.isHoldOn && app.isShowCollideAxisOn
                hold(app.UIAxes,'on');
                [nX,nY,tX,tY] = getCollideAxis(app.circle1, app.circle2, [app.circle2.finalX - app.circle1.finalX ; app.circle2.finalY - app.circle1.finalY]);
                plot(app.UIAxes, nX, nY, 'm', tX, tY, 'm');
            end
            app.circleMatrix = [app.circle1 , app.circle2];
            %app.actualTime = app.actualTime + app.time;
            disp('Fin de la visualisation de la simulation');
        end
        %Méthode permettant de calculer le nouveau vecteur vitesse après
        %collision
        function results = setNewVVector(app)
            disp('Début du calcul du nouveau vecteur vitesse');
            %Si il y a eu une collision entre des cercles
            if app.isThereACirclesCollide
                disp('Collisions des deux cercles');
                % Nouveaux vecteurs vitesses
                [v1Prim,v2Prim] = getNewVelocityVectorFromCircleCollide(app.circleMatrix(1, 1), app.circleMatrix(1, 2));
                app.circleMatrix(1, 1) = modifyCircle(app.circleMatrix(1, 1), app.circleMatrix(1, 1).finalX, app.circleMatrix(1, 1).finalY, v1Prim);
                app.circleMatrix(1, 2) = modifyCircle(app.circleMatrix(1, 2), app.circleMatrix(1, 2).finalX, app.circleMatrix(1, 2).finalY, v2Prim);
            else %Si il n'y a pas eu de collision entre les cercles
                %Si les cercles entrent en collision avec un côté en même
                %temps
                if  abs(app.time1 - app.time2) <= app.precision
                    vPrime = getNewVelocityVector(app.side1, app.circleMatrix(1, 1));
                    vPrime2 = getNewVelocityVector(app.side2, app.circleMatrix(1, 2));
                    app.circleMatrix(1, 1) = modifyCircle(app.circleMatrix(1, 1), app.circleMatrix(1, 1).finalX, app.circleMatrix(1, 1).finalY, vPrime);
                    app.circleMatrix(1, 2) = modifyCircle(app.circleMatrix(1, 2), app.circleMatrix(1, 2).finalX, app.circleMatrix(1, 2).finalY, vPrime2);
                else
                    %Si un seul cercle effectue sa collision
                    vPrime = getNewVelocityVector(app.side, app.circleMatrix(1, app.numCircleThatGoingToCollide));
                    app.circleMatrix(1, app.numCircleThatGoingToCollide) = modifyCircle(app.circleMatrix(1, app.numCircleThatGoingToCollide), app.circleMatrix(1, app.numCircleThatGoingToCollide).finalX, app.circleMatrix(1, app.numCircleThatGoingToCollide).finalY, vPrime);
                    app.circleMatrix(1, app.numOtherCircle) = modifyCircle(app.circleMatrix(1, app.numOtherCircle), app.circleMatrix(1, app.numOtherCircle).finalX, app.circleMatrix(1, app.numOtherCircle).finalY, [app.circleMatrix(1, app.numOtherCircle).vVector.projectedSpeedVx ; app.circleMatrix(1, app.numOtherCircle).vVector.projectedSpeedVy]);
                end
            end
            %Redéfinition des cercles
            app.circle1 = app.circleMatrix(1, 1);
            app.circle2 = app.circleMatrix(1, 2);
            [app.xCircle1_Initial , app.yCircle1_Initial] = getCoord(app.circle1);
            [app.vxc, app.vyc] = getVelocityComponents(app.circle1.vVector);
            [app.xCircle2_Initial,app.yCircle2_Initial] = getCoord(app.circle2);
            [app.vxc2,app.vyc2] = getVelocityComponents(app.circle2.vVector);
            app.speedThetaCircle1 = app.circle1.vVector.theta;
            app.speedThetaCircle2 = app.circle2.vVector.theta;
            app.isThereACirclesCollide = false;
            app.getFinalValues(app);
            disp('Fin du calcul du nouveau vecteur vitesse');
        end
        %Méthode permettant de reset les valeurs changée lors d'une
        %simulation
        function results = reset(app)
            app.xCircle1_Initial = app.firstXCircle1_Initial;
            app.yCircle1_Initial = app.firstYCircle1_Initial;
            app.xCircle2_Initial = app.firstXCircle2_Initial;
            app.yCircle2_Initial = app.firstYCircle2_Initial;
            app.CoordonnexducentreEditField.Value = app.xCircle1_Initial;
            app.CoordonneyducentreEditField.Value = app.yCircle1_Initial;
            app.CoordonnexducentreEditField_2.Value = app.xCircle2_Initial;
            app.CoordonneyducentreEditField_2.Value = app.yCircle2_Initial;
            app.speedThetaCircle1 = app.firstSpeedThetaCircle1;
            app.speedThetaCircle2 = app.firstSpeedThetaCircle2;
            app.simulationHasToBeStopped = false;
            app.CompteurdecollisionsEditField.Value = 0;
            app.TempsactuelEditField.Value = 0;
            app.actualTime = 0;
            app.actualTimeToShow = 0;
        end
        %Méthode permettant d'obtenir la masse d'un cercle en fonction de
        %sa masse volumique et de son rayon
        function mass = getMass(app, materials, radius)
            rho = materials.rho / 1000;
            volume = (4*pi*(radius)^3) / 3;
            mass = rho * volume;
        end
        %Méthode permettant d'afficher les valeurs après collision
        function results = getFinalValues(app)
            if app.speedThetaCircle1 < 0
                app.speedThetaCircle1 = 180 + app.speedThetaCircle1;
            end
            if app.speedThetaCircle2 <0
                app.speedThetaCircle2 = 180 + app.speedThetaCircle2;
            end
            app.VitessefinaleEditField.Value = app.circle1.vVector.speed;
            app.AnglevecteurvitessefinalEditField.Value = app.speedThetaCircle1;
            app.DirectionduvecteurvitessefinalGauge.Value = app.speedThetaCircle1;
            app.ComposanteVXEditField.Value = app.circle1.vVector.projectedSpeedVx;
            app.ComposanteVYEditField.Value = app.circle1.vVector.projectedSpeedVy;
            app.VitessefinaleEditField_2.Value = app.circle2.vVector.speed;
            app.AnglevecteurvitessefinalEditField_2.Value = app.speedThetaCircle2;
            app.DirectionduvecteurvitessefinalGauge_2.Value = app.speedThetaCircle2;
            app.ComposanteVXEditField_2.Value = app.circle2.vVector.projectedSpeedVx;
            app.ComposanteVYEditField_2.Value = app.circle2.vVector.projectedSpeedVy;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.xCircle1_Initial = 5;
            app.yCircle1_Initial = 5;
            app.radiusCircle1 = 1;
            app.materialsCircle1 = Materials.IRON;
            app.speedCircle1 = 20;
            app.speedThetaCircle1 = 0;
            %CERCLE 2
            app.xCircle2_Initial = 15;
            app.yCircle2_Initial = 15;
            app.radiusCircle2 = 1;
            app.materialsCircle2 = Materials.IRON;
            app.speedCircle2 = 20;
            app.speedThetaCircle2 = 0;
            %BOITE
            app.xBox_Initial = 0;
            app.yBox_Initial = 0;
            app.boxLenght = 30;
            app.boxHeight = 30;
            app.boxOrientation = 0;
            %SIMULATION
            app.collideNumber = 1;
            if app.isSimulatingWithCollideNumber == '1'
                app.TempsdesimulationEditField.Enable = 0;
                app.NombredecollisionEditField.Enable = 1;
            else
                app.TempsdesimulationEditField.Enable = 1;
                app.NombredecollisionEditField.Enable = 0;
            end
            app.RayonEditField.Value = app.radiusCircle1;
            app.RayonEditField_2.Value = app.radiusCircle2;
            app.VitesseEditField.Value = app.speedCircle1;
            app.VitesseEditField_2.Value = app.speedCircle2;
            app.CoordonnexducentreEditField.Value = app.xCircle1_Initial;
            app.CoordonneyducentreEditField.Value = app.yCircle1_Initial;
            app.CoordonnexducentreEditField_2.Value = app.xCircle2_Initial;
            app.CoordonneyducentreEditField_2.Value = app.yCircle2_Initial;
            app.MatriauDropDown.Value = 'IRON';
            app.MatriauDropDown_2.Value = 'IRON';
            app.DirectionduvecteurvitesseGauge.Value = app.speedThetaCircle1;
            app.DirectionduvecteurvitesseGauge_2.Value = app.speedThetaCircle2;
            app.DirectionduvecteurvitesseSlider.Value = app.speedThetaCircle1;
            app.DirectionduvecteurvitesseSlider_2.Value = app.speedThetaCircle2;
            app.DirectionOrientationDeLaBoite.Value = app.boxOrientation;
            app.OrientationdelaboteGauge.Value = app.boxOrientation;
            app.totalTime = 1;
            app.TempsdesimulationEditField.Value = app.totalTime;
            app.HauteurdelaboteEditField.Value = app.boxHeight;
            app.LargeurdelaboteEditField.Value = app.boxLenght;
            app.simulationHasToBeStopped = false;
            app.actualTime = 0;
            app.actualTimeToShow = 0;
            app.MasseEditField.Value = app.getMass(app, app.materialsCircle1, app.radiusCircle1);
            app.MasseEditField_2.Value = app.getMass(app, app.materialsCircle2, app.radiusCircle2);
            app.isHoldOn = false;
            app.isShowCollideAxisOn = false;
            app.isThereACirclesCollide = false;
        end

        % Value changed function: RayonEditField
        function RadiusCircle1Change(app, event)
            app.radiusCircle1 = app.RayonEditField.Value;
            app.MasseEditField.Value = app.getMass(app, app.materialsCircle1, app.radiusCircle1);
        end

        % Value changed function: RayonEditField_2
        function RadiusCircle2Change(app, event)
            app.radiusCircle2 = app.RayonEditField_2.Value;
            
        end

        % Value changed function: VitesseEditField
        function speedCircle1ChangeValue(app, event)
            app.speedCircle1 = app.VitesseEditField.Value;
            
        end

        % Value changed function: MatriauDropDown
        function materialsCircle1change(app, event)
           value = app.MatriauDropDown.Value;
           app.materialsCircle1 = getMaterials(app, value);
           app.MasseEditField.Value = app.getMass(app, app.materialsCircle1, app.radiusCircle1);
        end

        % Value changed function: CoordonnexducentreEditField
        function coordXCircle1ChangeValue(app, event)
            app.xCircle1_Initial = app.CoordonnexducentreEditField.Value;
            
        end

        % Value changed function: CoordonneyducentreEditField
        function coordYCircle1ChangeValue(app, event)
            app.yCircle1_Initial = app.CoordonneyducentreEditField.Value;
            
        end

        % Value changed function: DirectionduvecteurvitesseSlider
        function velocityVectorCircleOrientationChangeValue(app, event)
            app.speedThetaCircle1 = app.DirectionduvecteurvitesseSlider.Value;
            app.AnglevecteurvitesseEditField.Value = app.speedThetaCircle1;
            app.DirectionduvecteurvitesseGauge.Value = app.speedThetaCircle1;
        end

        % Value changing function: DirectionduvecteurvitesseSlider
        function velocityVectorCircle1ChangingValue(app, event)
            changingValue = event.Value;
            app.DirectionduvecteurvitesseGauge.Value = changingValue;
            app.AnglevecteurvitesseEditField.Value = changingValue;
        end

        % Value changed function: VitesseEditField_2
        function speedCircle2ChangeValue(app, event)
            app.speedCircle2 = app.VitesseEditField_2.Value;
        end

        % Value changed function: MatriauDropDown_2
        function materialsCircle2ChangeValue(app, event)
            value = app.MatriauDropDown_2.Value;
            app.materialsCircle2 = getMaterials(app, value);
        end

        % Value changed function: CoordonnexducentreEditField_2
        function coordXCircle2ChangeValue(app, event)
            app.xCircle2_Initial = app.CoordonnexducentreEditField_2.Value;
            
        end

        % Value changed function: CoordonneyducentreEditField_2
        function coordYCircle2ChangeValue(app, event)
            app.yCircle2_Initial = app.CoordonneyducentreEditField_2.Value;
            
        end

        % Value changed function: DirectionduvecteurvitesseSlider_2
        function velocityVectorCircle2ChangedValue(app, event)
            app.speedThetaCircle2 = app.DirectionduvecteurvitesseSlider_2.Value;
            app.DirectionduvecteurvitesseGauge_2.Value = app.speedThetaCircle2;
            app.AnglevecteurvitesseEditField_2.Value = app.speedThetaCircle2;
        end

        % Value changing function: DirectionduvecteurvitesseSlider_2
        function velocityVectorCircle2ChangingValue(app, event)
           changingValue = event.Value;
            app.DirectionduvecteurvitesseGauge_2.Value = changingValue;
            app.AnglevecteurvitesseEditField_2.Value = changingValue;
        end

        % Value changed function: DirectionOrientationDeLaBoite
        function boxOrientationChangeValue(app, event)
            app.boxOrientation = app.DirectionOrientationDeLaBoite.Value;
            
        end

        % Value changing function: DirectionOrientationDeLaBoite
        function boxOrientationchangingValue(app, event)
            changingValue = event.Value;
            app.OrientationdelaboteGauge.Value = changingValue;
        end

        % Value changed function: HauteurdelaboteEditField
        function boxHeightChangeValue(app, event)
            app.boxHeight = app.HauteurdelaboteEditField.Value;
            
        end

        % Value changed function: LargeurdelaboteEditField
        function boxLengthChangeValue(app, event)
            app.boxLenght = app.LargeurdelaboteEditField.Value;
            
        end

        % Button pushed function: SimulationButton
        function playSimulation(app, event)
            hold(app.UIAxes, 'off');
            hold off;
            app.SimulationOnAirLamp.Color = 'r';
            app.Simulation(app);
            app.SimulationOnAirLamp.Color = 'g';
            app.reset(app);
        end

        % Value changed function: 
        % SimulationparnombredecollisionSwitch
        function setOnSimulationWithCollide(app, event)
            app.isSimulatingWithCollideNumber = app.SimulationparnombredecollisionSwitch.Value;
            if app.isSimulatingWithCollideNumber == '1'
                app.TempsdesimulationEditField.Enable = 0;
                app.TempsdesimulationEditFieldLabel.Enable = 0;
                app.NombredecollisionEditField.Enable = 1;
                app.NombredecollisionEditFieldLabel.Enable = 1;
            else
                app.TempsdesimulationEditField.Enable = 1;
                app.TempsdesimulationEditFieldLabel.Enable = 1;
                app.NombredecollisionEditField.Enable = 0;
                app.NombredecollisionEditFieldLabel.Enable = 0;
            end
        end

        % Value changed function: NombredecollisionEditField
        function changeNumberCollide(app, event)
            app.collideNumber = app.NombredecollisionEditField.Value;
            
        end

        % Value changed function: TempsdesimulationEditField
        function changeTimeCollide(app, event)
            app.totalTime = app.TempsdesimulationEditField.Value;
            
        end

        % Button pushed function: InterruptsimulationButton
        function interruptSimulation(app, event)
            if ~app.simulationHasToBeStopped
                app.simulationHasToBeStopped = true;
            end
        end

        % Value changed function: MasseEditField
        function massCircle1Value(app, event)
            value = app.MasseEditField.Value;
            
        end

        % Value changed function: MasseEditField_2
        function massCircle2Value(app, event)
            value = app.MasseEditField_2.Value;
            
        end

        % Value changed function: AnglevecteurvitesseEditField
        function velocityVectorOrientation_1_ChangeValue(app, event)
            app.speedThetaCircle1 = app.AnglevecteurvitesseEditField.Value;
            app.DirectionduvecteurvitesseGauge.Value = app.speedThetaCircle1;
            app.DirectionduvecteurvitesseSlider.Value = app.speedThetaCircle1;
        end

        % Value changed function: AnglevecteurvitesseEditField_2
        function velocityVectorOrientation_2_ChangeValue(app, event)
            app.speedThetaCircle2 = app.AnglevecteurvitesseEditField_2.Value;
            app.DirectionduvecteurvitesseGauge_2.Value = app.speedThetaCircle2;
            app.DirectionduvecteurvitesseSlider_2.Value = app.speedThetaCircle2;
        end

        % Value changed function: HoldSwitch
        function setHoldMode(app, event)
            value = app.HoldSwitch.Value;
            if value == '1'
                disp('Hold activé');
                app.isHoldOn = true;
                app.isShowCollideAxisOn = false;
                app.AfficherAxesdecollisionsSwitch.Value = '0';
            else
                disp('Hold désactivé')
                app.isShowCollideAxisOn = false;
                app.isHoldOn = true;
            end
        end

        % Value changed function: AfficherAxesdecollisionsSwitch
        function setShowAxis(app, event)
            value = app.AfficherAxesdecollisionsSwitch.Value;
            if value =='1'
                app.isShowCollideAxisOn = true;
                app.isHoldOn = false;
                app.HoldSwitch.Value = '0';
            else
                app.isShowCollideAxisOn = false;
                app.isHoldOn = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UICollideSimUIFigure = uifigure('Visible', 'off');
            app.UICollideSimUIFigure.Name = 'UI Collide Sim';
            app.Panel = uipanel(app.UICollideSimUIFigure);
            app.Cercle1Panel = uipanel(app.Panel);
            app.Cercle1Panel.Title = 'Cercle 1';
            app.RayonEditFieldLabel = uilabel(app.Cercle1Panel);
            app.RayonEditFieldLabel.Text = 'Rayon';
            app.RayonEditField = uieditfield(app.Cercle1Panel, 'numeric');
            app.RayonEditField.Limits = [1e-05 25];
            app.RayonEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusCircle1Change, true);
            app.RayonEditField.Value = 1;
            app.VitesseEditFieldLabel = uilabel(app.Cercle1Panel);
            app.VitesseEditFieldLabel.Text = 'Vitesse';
            app.VitesseEditField = uieditfield(app.Cercle1Panel, 'numeric');
            app.VitesseEditField.Limits = [-500 500];
            app.VitesseEditField.ValueChangedFcn = createCallbackFcn(app, @speedCircle1ChangeValue, true);
            app.MatriauDropDownLabel = uilabel(app.Cercle1Panel);
            app.MatriauDropDownLabel.Text = 'Matériau';
            app.MatriauDropDown = uidropdown(app.Cercle1Panel);
            app.MatriauDropDown.Items = {'Fer', 'Argent', 'Or', 'Cuivre', 'Plomb', 'Bois', 'Verre', 'PVC', 'Nylon', 'Béton', 'Acier', 'Aluminium', 'Nickel', 'Laiton', 'Titane', 'Bronze', 'Tungstène', 'Lithium', 'Argile', 'Granite', 'Quartz', 'Marbre', 'Calcaire'};
            app.MatriauDropDown.ItemsData = {'IRON', 'SILVER', 'GOLD', 'COPPER', 'LEAD', 'WOOD', 'GLASS', 'PVC', 'NYLON', 'CONCRETE', 'STEEL', 'ALUMINIUM', 'NICKEL', 'TIN', 'TITANIUM', 'BRONZE', 'TUNGSTEN', 'LITHIUM', 'CLAY', 'GRANITE', 'QUARTZ', 'MARBLE', 'LIMESTONE'};
            app.MatriauDropDown.ValueChangedFcn = createCallbackFcn(app, @materialsCircle1change, true);
            app.MatriauDropDown.Value = 'IRON';
            app.CoordonnexducentreEditFieldLabel = uilabel(app.Cercle1Panel);
            app.CoordonnexducentreEditFieldLabel.Text = 'Coordonnée x du centre';
            app.CoordonnexducentreEditField = uieditfield(app.Cercle1Panel, 'numeric');
            app.CoordonnexducentreEditField.ValueChangedFcn = createCallbackFcn(app, @coordXCircle1ChangeValue, true);
            app.CoordonneyducentreEditFieldLabel = uilabel(app.Cercle1Panel);
            app.CoordonneyducentreEditFieldLabel.Text = 'Coordonnée y du centre';
            app.CoordonneyducentreEditField = uieditfield(app.Cercle1Panel, 'numeric');
            app.CoordonneyducentreEditField.ValueChangedFcn = createCallbackFcn(app, @coordYCircle1ChangeValue, true);
            app.DirectionduvecteurvitesseGaugeLabel = uilabel(app.Cercle1Panel);
            app.DirectionduvecteurvitesseGaugeLabel.Text = 'Direction du vecteur vitesse';
            app.DirectionduvecteurvitesseGauge = uigauge(app.Cercle1Panel, 'semicircular');
            app.DirectionduvecteurvitesseGauge.Limits = [0 180];
            app.DirectionduvecteurvitesseGauge.MajorTicks = [0 45 90 135 180];
            app.DirectionduvecteurvitesseGauge.ScaleDirection = 'counterclockwise';
            app.DirectionduvecteurvitesseSlider = uislider(app.Cercle1Panel);
            app.DirectionduvecteurvitesseSlider.Limits = [0 180];
            app.DirectionduvecteurvitesseSlider.MajorTicks = [0 90 180 270 360];
            app.DirectionduvecteurvitesseSlider.MajorTickLabels = {'0', '90', '180', '270', '360'};
            app.DirectionduvecteurvitesseSlider.ValueChangedFcn = createCallbackFcn(app, @velocityVectorCircleOrientationChangeValue, true);
            app.DirectionduvecteurvitesseSlider.ValueChangingFcn = createCallbackFcn(app, @velocityVectorCircle1ChangingValue, true);
            app.MasseEditFieldLabel = uilabel(app.Cercle1Panel);
            app.MasseEditFieldLabel.Text = 'Masse';
            app.MasseEditField = uieditfield(app.Cercle1Panel, 'numeric');
            app.MasseEditField.ValueChangedFcn = createCallbackFcn(app, @massCircle1Value, true);
            app.MasseEditField.Editable = 'off';
            app.AnglevecteurvitesseEditFieldLabel = uilabel(app.Cercle1Panel);
            app.AnglevecteurvitesseEditFieldLabel.Text = 'Angle vecteur vitesse';
            app.AnglevecteurvitesseEditField = uieditfield(app.Cercle1Panel, 'numeric');
            app.AnglevecteurvitesseEditField.Limits = [0 180];
            app.AnglevecteurvitesseEditField.ValueChangedFcn = createCallbackFcn(app, @velocityVectorOrientation_1_ChangeValue, true);
            app.gLabel = uilabel(app.Cercle1Panel);
            app.gLabel.Text = '[ g ]';
            app.Label = uilabel(app.Cercle1Panel);
            app.Label.Text = '[ ° ]';
            app.cmsLabel = uilabel(app.Cercle1Panel);
            app.cmsLabel.Text = '[ cm / s ]';
            app.cmLabel = uilabel(app.Cercle1Panel);
            app.cmLabel.Text = '[ cm ]';
            app.Cercle2Panel = uipanel(app.Panel);
            app.Cercle2Panel.Title = 'Cercle 2';
            app.RayonEditField_2Label = uilabel(app.Cercle2Panel);
            app.RayonEditField_2Label.Text = 'Rayon';
            app.RayonEditField_2 = uieditfield(app.Cercle2Panel, 'numeric');
            app.RayonEditField_2.Limits = [1e-05 25];
            app.RayonEditField_2.ValueChangedFcn = createCallbackFcn(app, @RadiusCircle2Change, true);
            app.RayonEditField_2.Value = 1;
            app.VitesseEditField_2Label = uilabel(app.Cercle2Panel);
            app.VitesseEditField_2Label.Text = 'Vitesse';
            app.VitesseEditField_2 = uieditfield(app.Cercle2Panel, 'numeric');
            app.VitesseEditField_2.Limits = [-500 500];
            app.VitesseEditField_2.ValueChangedFcn = createCallbackFcn(app, @speedCircle2ChangeValue, true);
            app.MatriauDropDown_2Label = uilabel(app.Cercle2Panel);
            app.MatriauDropDown_2Label.Text = 'Matériau';
            app.MatriauDropDown_2 = uidropdown(app.Cercle2Panel);
            app.MatriauDropDown_2.Items = {'Fer', 'Argent', 'Or', 'Cuivre', 'Plomb', 'Bois', 'Verre', 'PVC', 'Nylon', 'Béton', 'Acier', 'Aluminium', 'Nickel', 'Laiton', 'Titane', 'Bronze', 'Tungstène', 'Lithium', 'Argile', 'Granite', 'Quartz', 'Marbre', 'Calcaire'};
            app.MatriauDropDown_2.ItemsData = {'IRON', 'SILVER', 'GOLD', 'COPPER', 'LEAD', 'WOOD', 'GLASS', 'PVC', 'NYLON', 'CONCRETE', 'STEEL', 'ALUMINIUM', 'NICKEL', 'TIN', 'TITANIUM', 'BRONZE', 'TUNGSTEN', 'LITHIUM', 'CLAY', 'GRANITE', 'QUARTZ', 'MARBLE', 'LIMESTONE'};
            app.MatriauDropDown_2.ValueChangedFcn = createCallbackFcn(app, @materialsCircle2ChangeValue, true);
            app.MatriauDropDown_2.Value = 'IRON';
            app.CoordonnexducentreEditField_2Label = uilabel(app.Cercle2Panel);
            app.CoordonnexducentreEditField_2Label.Text = 'Coordonnée x du centre';
            app.CoordonnexducentreEditField_2 = uieditfield(app.Cercle2Panel, 'numeric');
            app.CoordonnexducentreEditField_2.ValueChangedFcn = createCallbackFcn(app, @coordXCircle2ChangeValue, true);
            app.CoordonneyducentreEditField_2Label = uilabel(app.Cercle2Panel);
            app.CoordonneyducentreEditField_2Label.Text = 'Coordonnée y du centre';
            app.CoordonneyducentreEditField_2 = uieditfield(app.Cercle2Panel, 'numeric');
            app.CoordonneyducentreEditField_2.ValueChangedFcn = createCallbackFcn(app, @coordYCircle2ChangeValue, true);
            app.DirectionduvecteurvitesseGauge_2Label = uilabel(app.Cercle2Panel);
            app.DirectionduvecteurvitesseGauge_2Label.Text = 'Direction du vecteur vitesse';
            app.DirectionduvecteurvitesseGauge_2 = uigauge(app.Cercle2Panel, 'semicircular');
            app.DirectionduvecteurvitesseGauge_2.Limits = [0 180];
            app.DirectionduvecteurvitesseGauge_2.MajorTicks = [0 45 90 135 180];
            app.DirectionduvecteurvitesseGauge_2.ScaleDirection = 'counterclockwise';
            app.DirectionduvecteurvitesseSlider_2 = uislider(app.Cercle2Panel);
            app.DirectionduvecteurvitesseSlider_2.Limits = [0 180];
            app.DirectionduvecteurvitesseSlider_2.MajorTicks = [0 90 180 270 360];
            app.DirectionduvecteurvitesseSlider_2.MajorTickLabels = {'0', '90', '180', '270', '360'};
            app.DirectionduvecteurvitesseSlider_2.ValueChangedFcn = createCallbackFcn(app, @velocityVectorCircle2ChangedValue, true);
            app.DirectionduvecteurvitesseSlider_2.ValueChangingFcn = createCallbackFcn(app, @velocityVectorCircle2ChangingValue, true);
            app.MasseEditField_2Label = uilabel(app.Cercle2Panel);
            app.MasseEditField_2Label.Text = 'Masse';
            app.MasseEditField_2 = uieditfield(app.Cercle2Panel, 'numeric');
            app.MasseEditField_2.ValueChangedFcn = createCallbackFcn(app, @massCircle2Value, true);
            app.MasseEditField_2.Editable = 'off';
            app.AnglevecteurvitesseEditField_2Label = uilabel(app.Cercle2Panel);
            app.AnglevecteurvitesseEditField_2Label.Text = 'Angle vecteur vitesse';
            app.AnglevecteurvitesseEditField_2 = uieditfield(app.Cercle2Panel, 'numeric');
            app.AnglevecteurvitesseEditField_2.Limits = [0 180];
            app.AnglevecteurvitesseEditField_2.ValueChangedFcn = createCallbackFcn(app, @velocityVectorOrientation_2_ChangeValue, true);
            app.gLabel_2 = uilabel(app.Cercle2Panel);
            app.gLabel_2.Text = '[ g ]';
            app.Label_2 = uilabel(app.Cercle2Panel);
            app.Label_2.Text = '[ ° ]';
            app.cmsLabel_2 = uilabel(app.Cercle2Panel);
            app.cmsLabel_2.Text = '[ cm / s ]';
            app.cmLabel_2 = uilabel(app.Cercle2Panel);
            app.cmLabel_2.Text = '[ cm ]';
            app.BotePanel = uipanel(app.Panel);
            app.BotePanel.Title = 'Boîte';
            app.OrientationdelaboteGaugeLabel = uilabel(app.BotePanel);
            app.OrientationdelaboteGaugeLabel.Text = 'Orientation de la boîte';
            app.OrientationdelaboteGauge = uigauge(app.BotePanel, 'ninetydegree');
            app.OrientationdelaboteGauge.Limits = [0 89];
            app.OrientationdelaboteGauge.MajorTicks = [0 45 89];
            app.OrientationdelaboteGauge.ScaleDirection = 'counterclockwise';
            app.DirectionOrientationDeLaBoite = uislider(app.BotePanel);
            app.DirectionOrientationDeLaBoite.Limits = [0 89];
            app.DirectionOrientationDeLaBoite.MajorTicks = [0 45 89];
            app.DirectionOrientationDeLaBoite.MajorTickLabels = {'0', '45', '89'};
            app.DirectionOrientationDeLaBoite.ValueChangedFcn = createCallbackFcn(app, @boxOrientationChangeValue, true);
            app.DirectionOrientationDeLaBoite.ValueChangingFcn = createCallbackFcn(app, @boxOrientationchangingValue, true);
            app.HauteurdelaboteEditFieldLabel = uilabel(app.BotePanel);
            app.HauteurdelaboteEditFieldLabel.Text = 'Hauteur de la boîte';
            app.HauteurdelaboteEditField = uieditfield(app.BotePanel, 'numeric');
            app.HauteurdelaboteEditField.Limits = [1 Inf];
            app.HauteurdelaboteEditField.ValueChangedFcn = createCallbackFcn(app, @boxHeightChangeValue, true);
            app.HauteurdelaboteEditField.Value = 1;
            app.LargeurdelaboteEditFieldLabel = uilabel(app.BotePanel);
            app.LargeurdelaboteEditFieldLabel.Text = 'Largeur de la boîte';
            app.LargeurdelaboteEditField = uieditfield(app.BotePanel, 'numeric');
            app.LargeurdelaboteEditField.Limits = [1 Inf];
            app.LargeurdelaboteEditField.ValueChangedFcn = createCallbackFcn(app, @boxLengthChangeValue, true);
            app.LargeurdelaboteEditField.Value = 1;
            app.cmLabel_3 = uilabel(app.BotePanel);
            app.cmLabel_3.Text = '[ cm ]';
            app.cmLabel_4 = uilabel(app.BotePanel);
            app.cmLabel_4.Text = '[ cm ]';
            app.SimulationOnAirLampLabel = uilabel(app.Panel);
            app.SimulationOnAirLampLabel.Text = 'Simulation On Air';
            app.SimulationOnAirLamp = uilamp(app.Panel);
            app.SimulationButton = uibutton(app.Panel, 'push');
            app.SimulationButton.ButtonPushedFcn = createCallbackFcn(app, @playSimulation, true);
            app.SimulationButton.Text = 'Simulation';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TempsdesimulationEditFieldLabel = uilabel(app.Panel);
            app.TempsdesimulationEditFieldLabel.Enable = 'off';
            app.TempsdesimulationEditFieldLabel.Text = 'Temps de simulation';
            app.TempsdesimulationEditField = uieditfield(app.Panel, 'numeric');
            app.TempsdesimulationEditField.Limits = [1 Inf];
            app.TempsdesimulationEditField.ValueChangedFcn = createCallbackFcn(app, @changeTimeCollide, true);
            app.TempsdesimulationEditField.Enable = 'off';
            app.TempsdesimulationEditField.Value = 1;
            app.SimulationparnombredecollisionSwitch = uiswitch(app.Panel, 'slider');
            app.SimulationparnombredecollisionSwitch.Items = {'Temps', 'Nombre de collision'};
            app.SimulationparnombredecollisionSwitch.ItemsData = {'0', '1'};
            app.SimulationparnombredecollisionSwitch.ValueChangedFcn = createCallbackFcn(app, @setOnSimulationWithCollide, true);
            app.SimulationparnombredecollisionSwitch.Value = '1';
            app.NombredecollisionEditFieldLabel = uilabel(app.Panel);
            app.NombredecollisionEditFieldLabel.Text = 'Nombre de collision';
            app.NombredecollisionEditField = uieditfield(app.Panel, 'numeric');
            app.NombredecollisionEditField.Limits = [1 Inf];
            app.NombredecollisionEditField.ValueChangedFcn = createCallbackFcn(app, @changeNumberCollide, true);
            app.NombredecollisionEditField.Value = 1;
            app.InterruptsimulationButton = uibutton(app.Panel, 'push');
            app.InterruptsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @interruptSimulation, true);
            app.InterruptsimulationButton.Text = 'Interrupt simulation';
            app.TempsactuelLabel = uilabel(app.Panel);
            app.TempsactuelLabel.Text = 'Temps actuel :';
            app.TempsactuelEditField = uieditfield(app.Panel, 'numeric');
            app.TempsactuelEditField.Limits = [0 Inf];
            app.TempsactuelEditField.Editable = 'off';
            app.CompteurdecollisionsEditFieldLabel = uilabel(app.Panel);
            app.CompteurdecollisionsEditFieldLabel.Text = 'Compteur de collisions';
            app.CompteurdecollisionsEditField = uieditfield(app.Panel, 'numeric');
            app.CompteurdecollisionsEditField.Editable = 'off';
            app.ResultatsPanel = uipanel(app.Panel);
            app.ResultatsPanel.Title = 'Resultats';
            app.Resultatcercle1Panel = uipanel(app.ResultatsPanel);
            app.Resultatcercle1Panel.Title = 'Resultat cercle 1';
            app.VitessefinaleEditFieldLabel = uilabel(app.Resultatcercle1Panel);
            app.VitessefinaleEditFieldLabel.Text = 'Vitesse finale';
            app.VitessefinaleEditField = uieditfield(app.Resultatcercle1Panel, 'numeric');
            app.VitessefinaleEditField.Editable = 'off';
            app.AnglevecteurvitessefinalEditFieldLabel = uilabel(app.Resultatcercle1Panel);
            app.AnglevecteurvitessefinalEditFieldLabel.Text = 'Angle vecteur vitesse final';
            app.AnglevecteurvitessefinalEditField = uieditfield(app.Resultatcercle1Panel, 'numeric');
            app.AnglevecteurvitessefinalEditField.Editable = 'off';
            app.DirectionduvecteurvitessefinalGaugeLabel = uilabel(app.Resultatcercle1Panel);
            app.DirectionduvecteurvitessefinalGaugeLabel.Text = 'Direction du vecteur vitesse final';
            app.DirectionduvecteurvitessefinalGauge = uigauge(app.Resultatcercle1Panel, 'semicircular');
            app.DirectionduvecteurvitessefinalGauge.Limits = [0 180];
            app.DirectionduvecteurvitessefinalGauge.MajorTicks = [0 45 90 135 180];
            app.DirectionduvecteurvitessefinalGauge.ScaleDirection = 'counterclockwise';
            app.ComposanteVXEditFieldLabel = uilabel(app.Resultatcercle1Panel);
            app.ComposanteVXEditFieldLabel.Text = 'Composante VX';
            app.ComposanteVXEditField = uieditfield(app.Resultatcercle1Panel, 'numeric');
            app.ComposanteVYEditFieldLabel = uilabel(app.Resultatcercle1Panel);
            app.ComposanteVYEditFieldLabel.Text = 'Composante VY';
            app.ComposanteVYEditField = uieditfield(app.Resultatcercle1Panel, 'numeric');
            app.cmsLabel_3 = uilabel(app.Resultatcercle1Panel);
            app.cmsLabel_3.Text = '[ cm / s ]';
            app.Label_4 = uilabel(app.Resultatcercle1Panel);
            app.Label_4.Text = '[ ° ]';
            app.Resultatcercle2Panel = uipanel(app.ResultatsPanel);
            app.Resultatcercle2Panel.Title = 'Resultat cercle 2';
            app.VitessefinaleEditField_2Label = uilabel(app.Resultatcercle2Panel);
            app.VitessefinaleEditField_2Label.Text = 'Vitesse finale';
            app.VitessefinaleEditField_2 = uieditfield(app.Resultatcercle2Panel, 'numeric');
            app.VitessefinaleEditField_2.Editable = 'off';
            app.AnglevecteurvitessefinalEditField_2Label = uilabel(app.Resultatcercle2Panel);
            app.AnglevecteurvitessefinalEditField_2Label.Text = 'Angle vecteur vitesse final';
            app.AnglevecteurvitessefinalEditField_2 = uieditfield(app.Resultatcercle2Panel, 'numeric');
            app.AnglevecteurvitessefinalEditField_2.Editable = 'off';
            app.DirectionduvecteurvitessefinalGauge_2Label = uilabel(app.Resultatcercle2Panel);
            app.DirectionduvecteurvitessefinalGauge_2Label.Text = 'Direction du vecteur vitesse final';
            app.DirectionduvecteurvitessefinalGauge_2 = uigauge(app.Resultatcercle2Panel, 'semicircular');
            app.DirectionduvecteurvitessefinalGauge_2.Limits = [0 180];
            app.DirectionduvecteurvitessefinalGauge_2.MajorTicks = [0 45 90 135 180];
            app.DirectionduvecteurvitessefinalGauge_2.ScaleDirection = 'counterclockwise';
            app.ComposanteVXEditField_2Label = uilabel(app.Resultatcercle2Panel);
            app.ComposanteVXEditField_2Label.Text = 'Composante VX';
            app.ComposanteVXEditField_2 = uieditfield(app.Resultatcercle2Panel, 'numeric');
            app.ComposanteVYEditField_2Label = uilabel(app.Resultatcercle2Panel);
            app.ComposanteVYEditField_2Label.Text = 'Composante VY';
            app.ComposanteVYEditField_2 = uieditfield(app.Resultatcercle2Panel, 'numeric');
            app.cmsLabel_4 = uilabel(app.Resultatcercle2Panel);
            app.cmsLabel_4.Text = '[ cm / s ]';
            app.Label_3 = uilabel(app.Resultatcercle2Panel);
            app.Label_3.Text = '[ ° ]';
            app.Vitesseparmthode2EditFieldLabel = uilabel(app.ResultatsPanel);
            app.Vitesseparmthode2EditFieldLabel.Text = 'Vitesse par méthode 2';
            app.Vitesseparmthode2EditField = uieditfield(app.ResultatsPanel, 'numeric');
            app.Vitesseparmthode2EditField.Editable = 'off';
            app.Vitesseparmthode2EditField_2Label = uilabel(app.ResultatsPanel);
            app.Vitesseparmthode2EditField_2Label.Text = 'Vitesse par méthode 2';
            app.Vitesseparmthode2EditField_2 = uieditfield(app.ResultatsPanel, 'numeric');
            app.Vitesseparmthode2EditField_2.Editable = 'off';
            app.cmsLabel_5 = uilabel(app.ResultatsPanel);
            app.cmsLabel_5.Text = '[ cm / s ]';
            app.cmsLabel_6 = uilabel(app.ResultatsPanel);
            app.cmsLabel_6.Text = '[ cm / s ]';
            app.sLabel = uilabel(app.Panel);
            app.sLabel.Text = '[ s ]';
            app.cmLabel_5 = uilabel(app.Panel);
            app.cmLabel_5.Text = '[ cm ]';
            app.cmLabel_6 = uilabel(app.Panel);
            app.cmLabel_6.Text = '[ cm ]';
            app.sLabel_2 = uilabel(app.Panel);
            app.sLabel_2.Text = '[ s ]';
            app.HoldSwitchLabel = uilabel(app.Panel);
            app.HoldSwitchLabel.Text = 'Hold';
            app.HoldSwitch = uiswitch(app.Panel, 'toggle');
            app.HoldSwitch.ItemsData = {'0', '1'};
            app.HoldSwitch.ValueChangedFcn = createCallbackFcn(app, @setHoldMode, true);
            app.HoldSwitch.Value = '0';
            app.AfficherAxesdecollisionsSwitchLabel = uilabel(app.Panel);
            app.AfficherAxesdecollisionsSwitchLabel.Text = {'Afficher '; 'Axes de collisions'};
            app.AfficherAxesdecollisionsSwitch = uiswitch(app.Panel, 'toggle');
            app.AfficherAxesdecollisionsSwitch.ItemsData = {'0', '1'};
            app.AfficherAxesdecollisionsSwitch.ValueChangedFcn = createCallbackFcn(app, @setShowAxis, true);
            app.AfficherAxesdecollisionsSwitch.Value = '0';
            % Show the figure after all components are created
            app.UICollideSimUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UICollideSimUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UICollideSimUIFigure)
        end
    end
end